classdef EDS_Grain_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        RegionEditField     matlab.ui.control.EditField
        RegionLabel         matlab.ui.control.Label
        GrainLabel          matlab.ui.control.Label
        FileLocationLabel   matlab.ui.control.Label
        SelectGrainButton   matlab.ui.control.Button
        SheetNameEditField  matlab.ui.control.EditField
        SheetNameLabel      matlab.ui.control.Label
        WriteDataButton     matlab.ui.control.Button
        LoadDataButton      matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        g = 1; % counter for grain selection
        n = 0;
        
        start_path
        write_path
        excel_filename
        full_excel_filename

        map_size
        data

        x
        y

        m = [0.75, -0.45];
        b = [0.83, 0.63];
        atomic_mass = [10.811, 12.011, 28.0855];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            cla(app.UIAxes)
            app.FileLocationLabel.Text = '';
        
            %% Prompt user to select 3 data files
            % Start in default folder

            if app.n == 0
                app.start_path = '*.csv';
            end
        
            app.UIFigure.Visible = 'off';

            [filename, path] = uigetfile(app.start_path,'Select EDS data files','MultiSelect','on');
            number_of_files = length(filename);

            app.UIFigure.Visible = 'on';
        
            folders_in_path = regexp(path,'[\\/]','split');
            num_folders = length(folders_in_path);
        
            if contains(path,'/')
                delim = '/';
            elseif contains(path,'\')
                delim = '\';
            end
        
            folders_in_path(num_folders-1:num_folders) = '';

            if app.n == 0
                app.write_path = append(strjoin(folders_in_path,delim),delim);
                app.start_path = append(app.write_path,'*.csv');
            else
                app.start_path = append(strjoin(folders_in_path,delim),delim,'*.csv');
            end
        
            % Define filename to write data to
            app.excel_filename = 'EDS Grain Analysis.xlsx';
            app.full_excel_filename = fullfile(app.write_path, app.excel_filename);
        
            %% Read Data
            app.map_size = size(readmatrix(strcat(path,filename{1})));
            app.data = zeros(app.map_size(1),app.map_size(2),number_of_files);
        
            for i = 1:number_of_files
                % create path to file
                mapFile = strcat(path,filename{i});
        
                % read the data file
                app.data(:,:,i) = readmatrix(mapFile); % loads data from the file into a matrix
            end

            % Remove tick labels
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};

            app.UIAxes.XLim = [1 app.map_size(2)];
            app.UIAxes.YLim = [1 app.map_size(1)];

%             app.UIAxes.YDir = 'reverse';

            app.UIAxes.Visible = 'on';
    
            imshow(app.data, 'Parent', app.UIAxes)

            hold on

            app.n = 1;
            app.x = [];
            app.y = [];
        end

        % Button pushed function: WriteDataButton
        function WriteDataButtonPushed(app, event)
            sheetname = app.SheetNameEditField.Value;

            if isempty(sheetname)
                sheetname = 'Default';
                % check if sheetname exceeds Excel's 31 character limit and truncate it
            elseif size(sheetname,2)>31
                sheetname = sheetname(1:31);
            end
            
            %% Compute average intensity for each grain
            [counts, intensity_ratio, scaling_factor, rel_intensity, wt_fraction, mol, at_percent] = deal(ones(app.n - 1,3));
        
            for i = 1:app.n - 1
                counts(i,1) = mean(app.data(app.y(i,1):app.y(i,2), app.x(i,1):app.x(i,2), 1), 'all');
                counts(i,2) = mean(app.data(app.y(i,1):app.y(i,2), app.x(i,1):app.x(i,2), 2), 'all');
                counts(i,3) = mean(app.data(app.y(i,1):app.y(i,2), app.x(i,1):app.x(i,2), 3), 'all');
            end
    
            %% Apply the Scaling Factors
            for i = 2:3
                % compute elemental intensity ratios to be used in scaling factor calcs
                intensity_ratio(:,i) = counts(:,i) ./ counts(:,1);
                % calculate scaling factors for each point along line averaged intensity
                scaling_factor(:,i) = app.b(i-1) * exp(app.m(i-1) .* intensity_ratio(:,i));
                % apply scaling factors to get corrected relative intensity of each element
                rel_intensity(:,i) = scaling_factor(:,i) .* intensity_ratio(:,i);
            end
            
            total_intensity = rel_intensity(:,1) + rel_intensity(:,2) + rel_intensity(:,3);
        
            for i = 1:3
                % convert corrected intensities to weight fractions
                wt_fraction(:,i) = rel_intensity(:,i) ./ total_intensity;
                % convert weight fractions to relative mol fraction
                mol(:,i) = wt_fraction(:,i) / app.atomic_mass(i);
            end
        
            % convert relative mol fraction to atomic percents
            total_mols = mol(:,1) + mol(:,2) + mol(:,3);
        
            for i = 1:3
                at_percent(:,i) = 100 * mol(:,i) ./ total_mols;
            end
        
            b_to_c_ratio = at_percent(:,1) ./ at_percent(:,2);
        
            %% Prepare data for export
            % combine the 2theta, counts, and normalized counts columns
            column_data = array2table(num2cell([app.x, app.y, at_percent, b_to_c_ratio]));
        
            % create data headers to label the columns]
            region_label = {app.RegionEditField.Value, blanks(1), blanks(1), blanks(1), blanks(1), blanks(1), blanks(1), blanks(1)};
            column_labels = {'X Start', 'X End', 'Y Start', 'Y End', 'B (at.%)', 'C (at.%)', 'Si (at.%)', 'B/C Ratio'};
            separator = {blanks(1), blanks(1), blanks(1), blanks(1), blanks(1), blanks(1), blanks(1), blanks(1)};
        
            % combine data and headers into one big table to write to Excel
            composite_data = [region_label; column_labels; column_data; separator];
        
            %% Write data to output Excel spreadsheet
            writetable(composite_data,app.full_excel_filename,'Sheet',sheetname,'WriteVariableNames',false,'WriteMode','append');
        
            % final printout
            app.FileLocationLabel.Text = append('Sheet written to ', app.excel_filename, ' in ', app.write_path);
        end

        % Button pushed function: SelectGrainButton
        function SelectGrainButtonPushed(app, event)
            [x_coord(1,1), y_coord(1,1)] = ginput(1);

            pt1 = scatter(x_coord(1,1), y_coord(1,1), 'w', 'filled', 'Parent', app.UIAxes)

            [x_coord(1,2), y_coord(1,2)] = ginput(1);

            pt2 = scatter(x_coord(1,2), y_coord(1,2), 'w', 'filled', 'Parent', app.UIAxes)
            
            % Determine corner and dimensions
            x_min = min(x_coord); x_max = max(x_coord);
            y_min = min(y_coord); y_max = max(y_coord);
            w = x_max - x_min;
            h = y_max - y_min;

            if (x_min < 1) || (y_min < 1) || (x_max > app.map_size(2)) || (y_max > app.map_size(1))
                app.FileLocationLabel.Text = 'Grain selection area outside of axis bounds!';
                delete(pt1)
                delete(pt2)
            else
                app.FileLocationLabel.Text = '';

                % Plot rectangle on top of image
                rectangle('Position', [x_min y_min w h], 'EdgeColor', 'w', 'LineWidth', 2, 'Parent', app.UIAxes)
        
                % Store values
                % Start is bottom left and end is top right
                app.x(app.n,1) = ceil(x_min); app.x(app.n,2) = floor(x_max);
                app.y(app.n,1) = ceil(y_min); app.y(app.n,2) = floor(y_max);
                
                app.n = app.n + 1;
                app.g = app.g + 1;
                app.GrainLabel.Text = append('Grain: ', int2str(app.g));
            end
        end

        % Value changed function: SheetNameEditField
        function SheetNameEditFieldValueChanged(app, event)
            app.g = 0;
            app.GrainLabel.Text = append('Grain: ', int2str(app.g));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.ZTick = [];
            app.UIAxes.ZTickLabel = '';
            app.UIAxes.Visible = 'off';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.WriteDataButton = uibutton(app.UIFigure, 'push');
            app.WriteDataButton.ButtonPushedFcn = createCallbackFcn(app, @WriteDataButtonPushed, true);
            app.WriteDataButton.Text = 'Write Data';
            app.SheetNameLabel = uilabel(app.UIFigure);
            app.SheetNameLabel.Text = 'Sheet Name:';
            app.SheetNameEditField = uieditfield(app.UIFigure, 'text');
            app.SheetNameEditField.ValueChangedFcn = createCallbackFcn(app, @SheetNameEditFieldValueChanged, true);
            app.SelectGrainButton = uibutton(app.UIFigure, 'push');
            app.SelectGrainButton.ButtonPushedFcn = createCallbackFcn(app, @SelectGrainButtonPushed, true);
            app.SelectGrainButton.Text = 'Select Grain';
            app.FileLocationLabel = uilabel(app.UIFigure);
            app.FileLocationLabel.Text = 'File Location';
            app.GrainLabel = uilabel(app.UIFigure);
            app.GrainLabel.Text = 'Grain: 0';
            app.RegionLabel = uilabel(app.UIFigure);
            app.RegionLabel.Text = 'Region:';
            app.RegionEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EDS_Grain_Analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end